classdef healthMapApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        LoadHealthMapButton  matlab.ui.control.Button
        BuildSlider          matlab.ui.control.Slider
        BuildSliderLabel     matlab.ui.control.Label
        Tree                 matlab.ui.container.CheckBoxTree
        Node                 matlab.ui.container.TreeNode
        Node2                matlab.ui.container.TreeNode
        Node3                matlab.ui.container.TreeNode
        Node4                matlab.ui.container.TreeNode
        UIAxes               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        healthMap % the healthMap table
    end
    
    methods (Access = private)
        
        function refreshLoadUI(app)
            checksize = size(app.healthMap);
            
            if app.
        end
                
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: BuildSlider
        function BuildSliderValueChanged(app, event)
            value = app.BuildSlider.Value;
            
        end

        % Callback function
        function ButtonValueChanged(app, event)

        end

        % Button pushed function: LoadHealthMapButton
        function LoadHealthMapButtonPushed(app, event)
            app.LoadHealthMapButton.BackgroundColor = [0.94,0.94,0.94];
            [fn, fp, index] = uigetfile('*.xlsx');
            if index == 0
                app.LoadHealthMapButton.Text = 'No file selected!';
                app.LoadHealthMapButton.BackgroundColor = [1.00,0.00,0.00];
            else
                app.healthMap = readtable(strcat(fp,fn), 'Sheet', "Health Map");
                app.LoadHealthMapButton.Text = 'Success!';
                app.LoadHealthMapButton.BackgroundColor = [0.39,0.83,0.07];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Tree = uitree(app.UIFigure, 'checkbox');
            app.Node = uitreenode(app.Tree);
            app.Node.Text = 'Node';
            app.Node2 = uitreenode(app.Node);
            app.Node2.Text = 'Node2';
            app.Node3 = uitreenode(app.Node);
            app.Node3.Text = 'Node3';
            app.Node4 = uitreenode(app.Node);
            app.Node4.Text = 'Node4';
            app.BuildSliderLabel = uilabel(app.UIFigure);
            app.BuildSliderLabel.Text = 'Build';
            app.BuildSlider = uislider(app.UIFigure);
            app.BuildSlider.ValueChangedFcn = createCallbackFcn(app, @BuildSliderValueChanged, true);
            app.LoadHealthMapButton = uibutton(app.UIFigure, 'push');
            app.LoadHealthMapButton.ButtonPushedFcn = createCallbackFcn(app, @LoadHealthMapButtonPushed, true);
            app.LoadHealthMapButton.Text = 'Load Health Map';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = healthMapApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end